classdef Skynet_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Button                    matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Joint1Label               matlab.ui.control.Label
        Button_3                  matlab.ui.control.Button
        Button_4                  matlab.ui.control.Button
        Joint2Label               matlab.ui.control.Label
        Button_5                  matlab.ui.control.Button
        Button_6                  matlab.ui.control.Button
        Joint3Label               matlab.ui.control.Label
        Button_7                  matlab.ui.control.Button
        Button_8                  matlab.ui.control.Button
        Joint4Label               matlab.ui.control.Label
        Button_9                  matlab.ui.control.Button
        Button_10                 matlab.ui.control.Button
        Joint5Label               matlab.ui.control.Label
        Button_11                 matlab.ui.control.Button
        Button_12                 matlab.ui.control.Button
        Joint6Label               matlab.ui.control.Label
        RobotDropDownLabel        matlab.ui.control.Label
        RobotDropDown             matlab.ui.control.DropDown
        DrinksMenuButtonGroup     matlab.ui.container.ButtonGroup
        SpriteButton_2            matlab.ui.control.Button
        ColaButton_2              matlab.ui.control.Button
        VodkaButton               matlab.ui.control.Button
        RumButton                 matlab.ui.control.Button
        ResetEStopSwitch          matlab.ui.control.Switch
        EStopButton               matlab.ui.control.StateButton
        PositionEditFieldLabel    matlab.ui.control.Label
        PositionEditField         matlab.ui.control.NumericEditField
        PositionEditField_2Label  matlab.ui.control.Label
        PositionEditField_2       matlab.ui.control.NumericEditField
        PositionEditField_3Label  matlab.ui.control.Label
        PositionEditField_3       matlab.ui.control.NumericEditField
        PositionEditField_4Label  matlab.ui.control.Label
        PositionEditField_4       matlab.ui.control.NumericEditField
        PositionEditField_5Label  matlab.ui.control.Label
        PositionEditField_5       matlab.ui.control.NumericEditField
        PositionEditField_6Label  matlab.ui.control.Label
        PositionEditField_6       matlab.ui.control.NumericEditField
        ToDoImplementSafetyCiircuitLabel  matlab.ui.control.Label
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.EditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.EditField
        ZEditFieldLabel           matlab.ui.control.Label
        ZEditField                matlab.ui.control.EditField
        RollEditFieldLabel        matlab.ui.control.Label
        RollEditField             matlab.ui.control.EditField
        PitchEditFieldLabel       matlab.ui.control.Label
        PitchEditField            matlab.ui.control.EditField
        YawEditFieldLabel         matlab.ui.control.Label
        YawEditField              matlab.ui.control.EditField
        SetPositionButton         matlab.ui.control.Button
        HomeButton                matlab.ui.control.Button
        ClearButton               matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        ResetEStopButton          matlab.ui.control.Button
        CollisionAvoidanceExampleButton  matlab.ui.control.Button
        SensorInputExampleButton  matlab.ui.control.Button
    end


    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VodkaButton
        function VodkaButtonPushed(app, event)
            
            ur3 = evalin('base', 'ur3');
            kuka = evalin('base', 'kuka');
            Vodka_h = evalin('base', 'Vodka_h');
            vertices = evalin('base', 'ur3');
            transformedVertices = evalin('base', 'transformedVertices');
            setVodka = evalin('base', 'setVodka');
            Vodka(ur3,kuka,Vodka_h,vertices,transformedVertices,setVodka);
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '-';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '+';
            app.Joint1Label = uilabel(app.UIFigure);
            app.Joint1Label.Text = 'Joint 1';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '-';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '+';
            app.Joint2Label = uilabel(app.UIFigure);
            app.Joint2Label.Text = 'Joint 2';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '-';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '+';
            app.Joint3Label = uilabel(app.UIFigure);
            app.Joint3Label.Text = 'Joint 3';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '-';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '+';
            app.Joint4Label = uilabel(app.UIFigure);
            app.Joint4Label.Text = 'Joint 4';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Text = '-';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.Text = '+';
            app.Joint5Label = uilabel(app.UIFigure);
            app.Joint5Label.Text = 'Joint 5';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.Text = '-';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.Text = '+';
            app.Joint6Label = uilabel(app.UIFigure);
            app.Joint6Label.Text = 'Joint 6';
            app.RobotDropDownLabel = uilabel(app.UIFigure);
            app.RobotDropDownLabel.Text = 'Robot';
            app.RobotDropDown = uidropdown(app.UIFigure);
            app.RobotDropDown.Items = {'KUKA', 'UR3'};
            app.RobotDropDown.Value = 'KUKA';
            app.DrinksMenuButtonGroup = uibuttongroup(app.UIFigure);
            app.DrinksMenuButtonGroup.Title = 'Drinks Menu';
            app.SpriteButton_2 = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.SpriteButton_2.Text = {'Sprite'; ''};
            app.ColaButton_2 = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.ColaButton_2.Text = 'Cola';
            app.VodkaButton = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.VodkaButton.ButtonPushedFcn = createCallbackFcn(app, @VodkaButtonPushed, true);
            app.VodkaButton.Text = 'Vodka';
            app.RumButton = uibutton(app.DrinksMenuButtonGroup, 'push');
            app.RumButton.Text = {'Rum'; ''};
            app.ResetEStopSwitch = uiswitch(app.UIFigure, 'slider');
            app.EStopButton = uibutton(app.UIFigure, 'state');
            app.EStopButton.Text = 'E-Stop';
            app.PositionEditFieldLabel = uilabel(app.UIFigure);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.UIFigure, 'numeric');
            app.PositionEditField_2Label = uilabel(app.UIFigure);
            app.PositionEditField_2Label.Text = 'Position';
            app.PositionEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PositionEditField_3Label = uilabel(app.UIFigure);
            app.PositionEditField_3Label.Text = 'Position';
            app.PositionEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.PositionEditField_4Label = uilabel(app.UIFigure);
            app.PositionEditField_4Label.Text = 'Position';
            app.PositionEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.PositionEditField_5Label = uilabel(app.UIFigure);
            app.PositionEditField_5Label.Text = 'Position';
            app.PositionEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.PositionEditField_6Label = uilabel(app.UIFigure);
            app.PositionEditField_6Label.Text = 'Position';
            app.PositionEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.ToDoImplementSafetyCiircuitLabel = uilabel(app.UIFigure);
            app.ToDoImplementSafetyCiircuitLabel.Text = {'To Do - '; '- Implement Safety Ciircuit'; '- Look at implementing sensors'; 'and tracking output to a screen'; '-Use those sensors to trip or reset'; 'safery circuit and estop'};
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'text');
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'text');
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'text');
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'text');
            app.SetPositionButton = uibutton(app.UIFigure, 'push');
            app.SetPositionButton.Text = 'Set Position';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.Text = 'Home';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.Text = 'Clear';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.ResetEStopButton = uibutton(app.UIFigure, 'push');
            app.ResetEStopButton.Text = 'Reset E-Stop';
            app.CollisionAvoidanceExampleButton = uibutton(app.UIFigure, 'push');
            app.CollisionAvoidanceExampleButton.Text = 'Collision Avoidance Example';
            app.SensorInputExampleButton = uibutton(app.UIFigure, 'push');
            app.SensorInputExampleButton.Text = 'Sensor Input Example';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Skynet_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end